classdef WLAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WIFITestUIFigure          matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        NewMenu                   matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        SettingMenu               matlab.ui.container.Menu
        VSGMenu                   matlab.ui.container.Menu
        VSAMenu                   matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        LinkLabel                 matlab.ui.control.Label
        TilteLabel                matlab.ui.control.Label
        Panel3                    matlab.ui.container.Panel
        DCButtonGroup             matlab.ui.container.ButtonGroup
        V30EditField1             matlab.ui.control.NumericEditField
        V15EditField1             matlab.ui.control.NumericEditField
        V30Label1                 matlab.ui.control.Label
        V30CurEditField1          matlab.ui.control.NumericEditField
        DC_Button2                matlab.ui.control.Button
        DC_Button1                matlab.ui.control.Button
        ILabel1                   matlab.ui.control.Label
        V15EditField2             matlab.ui.control.NumericEditField
        V30EditField2             matlab.ui.control.NumericEditField
        V15Label1                 matlab.ui.control.Label
        V30Label2                 matlab.ui.control.Label
        V15Label2                 matlab.ui.control.Label
        V15CurEditField1          matlab.ui.control.NumericEditField
        ILabel2                   matlab.ui.control.Label
        V30CurEditField2          matlab.ui.control.NumericEditField
        ILabel3                   matlab.ui.control.Label
        V15CurEditField2          matlab.ui.control.NumericEditField
        ILabel4                   matlab.ui.control.Label
        MXAButton                 matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        Panel2                    matlab.ui.container.Panel
        PowerMeterEditFieldLabel  matlab.ui.control.Label
        PowerMeterEditField       matlab.ui.control.NumericEditField
        dBLabel                   matlab.ui.control.Label
        Panel1                    matlab.ui.container.Panel
        WIFIMXGListBoxLabel       matlab.ui.control.Label
        WIFIMXGListBox            matlab.ui.control.ListBox
        TabGroup                  matlab.ui.container.TabGroup
        BasebandWaveformTab       matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        VITab                     matlab.ui.container.Tab
        UIAxes6                   matlab.ui.control.UIAxes
        PSDTab                    matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        ContellationDiagramTab    matlab.ui.container.Tab
        UIAxes4                   matlab.ui.control.UIAxes
        SpectrumAnalyzerTab       matlab.ui.container.Tab
        UIAxes5                   matlab.ui.control.UIAxes
        Button                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        BoardIndex1 = 9;DCaddress1 = 7;       
        BoardIndex2 = 8;DCaddress2 = 8;
        
    end
    methods (Access = private)
               
        function [time,vhtWaveform] = Waveform_ac(~)
            vht = wlanVHTConfig;
            bits = [1;0;0;1;1];
            vhtWaveform = wlanWaveformGenerator(bits,vht, ...
                'NumPackets',5,'IdleTime',20e-6);
            fs = 80e6; % Set sampling frequency equal to the channel bandwidth
            time = ([0:length(vhtWaveform)-1]/fs)*1e6;
        end
        
        function [time,htWaveform] = Waveform_n(~)
            ht = wlanHTConfig;
            bits = [1;0;0;1;1];
            htWaveform = wlanWaveformGenerator(bits,ht, ...
                'NumPackets',5,'IdleTime',30e-6);
            fs = 20e6; % Set sampling frequency equal to the channel bandwidth
            time = ([0:length(htWaveform)-1]/fs)*1e6;
        end
        
        function [time,heWaveform] = Waveform_ax(~)
            he=heSUConfig;
            bits = [1;0;0;1;1];
            heWaveform =heWaveformGenerator(bits,he, ...
                'NumPackets',5,'IdleTime',10e-6);
            %fs = heSampleRate(he);
            fs = 160e6; % Set sampling frequency equal to the channel bandwidth
            time = ([0:length(heWaveform)-1]/fs)*1e6;
        end
        
        function [time,nhtDSSSWaveform] = Waveform_b(~)
            nht = wlanNonHTConfig('Modulation','DSSS','DataRate','2Mbps');
            bits = [1;0;0;1;1];
            nhtDSSSWaveform = wlanWaveformGenerator(bits,nht, ...
                'NumPackets',5,'IdleTime',5e-6);
            fs = 11e6; % Set sampling frequency equal to the channel bandwidth
            time = ([0:length(nhtDSSSWaveform)-1]/fs)*1e6;
        end
        
        function [time,nhtWaveform] = Waveform_a(~)
            clear;
            nht = wlanNonHTConfig;
            bits = [1;0;0;1;1];
            nhtWaveform = wlanWaveformGenerator(bits,nht, ...
                'NumPackets',5,'IdleTime',45e-6);
            fs = 20e6; % Set sampling frequency equal to the channel bandwidth
            time = ([0:length(nhtWaveform)-1]/fs)*1e6;
        end
        
        function [CP30,CP15]=  DCsupmes(~,BoardIndex,DCaddress,Volt30,Volt15)
            oldObjects=instrfind;
            if ~isempty(oldObjects)
                delete(oldObjects);
                clear oldObjects;
            end
            %             addressline=sprintf('visaObj=visa(''agilent'',''%s'');',DCaddress);
            %             eval(addressline);
            Obj = gpib('KEYSIGHT',BoardIndex,DCaddress);
            set(Obj, 'InputBufferSize', 100000);
            set(Obj, 'Timeout', 30);
            fopen(Obj);
            %环境初始化
            fprintf(Obj,':SYST:PRES');
            fprintf(Obj,'*CLS');
            %fprintf(visaObj,"APPL P30V,3.0,1.0");
            %P30Vstr=['APPL P30V ,' num2str(Volt30)];
            %P15Vstr=['APPL P30V ,' num2str(Volt15)];
            P30Vstr=['VOLT ' num2str(Volt30)];
            P15Vstr=['VOLT ' num2str(Volt15)];
            fprintf(Obj,'INST P30V');
            fprintf(Obj,P30Vstr);
            fprintf(Obj,'OUTP:STAT ON');
            CP30=query(Obj,'MEAS:CURR:DC? P30V');%测量+30端口的电流值
            fprintf(Obj,'INST P15V');
            fprintf(Obj,P15Vstr);           
            CP15=query(Obj,'MEAS:CURR:DC? P15V');%测量+15V端口的电流值
            CP30=str2double(CP30);
            CP15=str2double(CP15);
            fprintf(Obj,'OUTP:STAT OFF');
        end
        
        function  DCplot(app,BoardIndex,DCaddress,V30,V15)
            oldObjects=instrfind;
            if ~isempty(oldObjects)
                delete(oldObjects);
                clear oldObjects;
            end
%             addressline=sprintf('Obj=visa(''agilent'',''%s'');',DCaddress);
%             eval(addressline);
            Obj = gpib('KEYSIGHT',BoardIndex,DCaddress);
%             resourceStruct = instrhwinfo('visa','agilent');
%             Obj = visa('agilent','GPIB::8::INSTR');
            set(Obj, 'InputBufferSize', 100000);
            set(Obj, 'Timeout', 30);
            fopen(Obj);
            %环境初始化
            fprintf(Obj,':SYST:PRES');
            fprintf(Obj,'*CLS');
            %fprintf(visaObj,"APPL P30V,3.0,1.0");
%             P30Vstr=['APPL P30V, ' num2str(Volt30)];
%             P15Vstr=['APPL P15V, ' num2str(Volt15)];
           
            P15Vvec=[0:0.5:V15];
            for i=1:length(P15Vvec)
                P15Vstr=['APPL ' num2str(P15Vvec(i)) ',2.0'];
                fprintf(Obj,P15Vstr);
                fprintf(Obj,'*OPC');
                fprintf(Obj,'OUTP:STAT ON');
                VP15V(i)=str2num(query(Obj,'MEAS:VOLT:DC? P15V'));
                C15(i)=str2num(query(Obj,'MEAS:CURR:DC? P15V'));
            end
            P30Vvec=[0:0.5:V30];
            for i=1:length(P30Vvec)
                P30Vstr=['APPL ' num2str(P30Vvec(i)) ',2.0'];
                fprintf(Obj,P30Vstr);
                fprintf(Obj,'*OPC');
                fprintf(Obj,'OUTP:STAT ON');
                VP30V(i)=str2num(query(Obj,'MEAS:VOLT:DC? P30V'));
                C30(i)=str2num(query(Obj,'MEAS:CURR:DC? P30V'));
            end
            cla(app.UIAxes6,'reset');
            plot(app.UIAxes6,VP15V,C15,VP30V,C30);
            xlabel(app.UIAxes6,'V');
            ylabel(app.UIAxes6,'I');
            legend(app.UIAxes6,'P15V/I','P30V/I'); 
            title(app.UIAxes6,'I/V Sweep');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             imshow('hatchip.png','parent',app.UIAxes)
            % I = imread('logo1.png');
            %imshow(I,'parent',app.UIAxes)
        end

        % Callback function
        function powermeter(app, event)
            
        end

        % Value changed function: PowerMeterEditField
        function PowerMeterEditFieldValueChanged(app, event)
            address = 'GPIB0::5::INSTR';
            oldObjects=instrfind;
            if ~isempty(oldObjects)
                delete(oldObjects);
                clear oldObjects;
            end
            
            addressline=sprintf('visaObj=visa(''agilent'',''%s'');',address);
            eval(addressline);
            set(visaObj, 'InputBufferSize', 100000);
            set(visaObj, 'Timeout', 30);
            fopen(visaObj);
            %环境初始化
            fprintf(visaObj,':SYST:PRES');
            fprintf(visaObj,'*CLS');
            %设置输出数据格式为ASCii
            fprintf(visaObj,':FORMat:DATA ASCII');
            fprintf(visaObj,':FORMat:BORDer SWAPPED');
            powermes=query(visaObj,'FETCH1:POW:AC?');
            powervalue=str2double(powermes);
            app.PowerMeterEditField.Value=powervalue;
        end

        % Callback function
        function MXGDropDownValueChanged(app, event)
            
        end

        % Close request function: WIFITestUIFigure
        function WIFITestUIFigureCloseRequest(app, event)
            msg = 'Are you sure you want to close?';
            selection = uiconfirm(app.WIFITestUIFigure,msg,'Close APP','Icon','warning');
            %selection = uiconfirm(app.WIFITestUIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
            switch selection
                case '确定'
                    delete(app)
                case '取消'
                    return
            end
        end

        % Button pushed function: MXAButton
        function MXAButtonPushed(app, event)
            imshow('PSD.png','parent',app.UIAxes3);
            imshow('CD.png','parent',app.UIAxes4);
            imshow('SA.png','parent',app.UIAxes5);
        end

        % Callback function
        function PowerMeterEditFieldValueChanged2(app, event)
            
        end

        % Callback function
        function DCSupplyEditFieldValueChanged(app, event)
            address = 'GPIB0::5::INSTR';
            oldObjects=instrfind;
            if ~isempty(oldObjects)
                delete(oldObjects);
                clear oldObjects;
            end
            addressline=sprintf('visaObj=visa(''agilent'',''%s'');',address);
            eval(addressline);
            set(visaObj, 'InputBufferSize', 100000);
            set(visaObj, 'Timeout', 30);
            fopen(visaObj);
            
            %环境初始化
            fprintf(visaObj,':SYST:PRES');
            fprintf(visaObj,'*CLS');
            
            CP30=query(visaObj,'MEAS:CURR:DC? P30V');%测量+25端口的电流值
            CP15=query(visaObj,'MEAS:CURR:DC? P15V');%测量+6V端口的电流值
            
            CP30=str2double(CP30);
            CP15=str2double(CP15);
            app.V30EditField1.Value = CP30;
            app.V15EditField1.Value = CP15;
            
        end

        % Callback function
        function Waveform(app, event)
            
            
        end

        % Value changed function: WIFIMXGListBox
        function Selectwaveform(app, event)
            value = app.WIFIMXGListBox.Value;
            switch value
                case '选择波形'
                    cla(app.UIAxes2,'reset');
                case '802.11a/g'
                    cla(app.UIAxes2,'reset');
                    [time,nhtWaveform] = Waveform_a(app);
                    plot(app.UIAxes2,time,abs(nhtWaveform))
                    xlabel (app.UIAxes2,'Time (microseconds)');
                    ylabel(app.UIAxes2,'Magnitude');
                    title(app.UIAxes2,'Baseband IEEE 802.11a Waveform');
                case '802.11b'
                    cla(app.UIAxes2,'reset');
                    [time,nhtDSSSWaveform] = Waveform_b(app);
                    plot(app.UIAxes2,time,real(nhtDSSSWaveform),'*')
                    xlabel (app.UIAxes2,'Time (microseconds)');
                    ylabel(app.UIAxes2,'Re[nhtDSSSWaveform]');
                    axis(app.UIAxes2,[0,10,-1.1,1.1]);
                    title(app.UIAxes2,'Baseband IEEE 802.11b Waveform');
                case '802.11n'
                    cla(app.UIAxes2,'reset');
                    [time,htWaveform] = Waveform_n(app);
                    plot(app.UIAxes2,time,abs(htWaveform));
                    xlabel (app.UIAxes2,'Time (microseconds)');
                    ylabel(app.UIAxes2,'Magnitude');
                    title(app.UIAxes2,'Baseband IEEE 802.11n Waveform');
                case '802.11ac'
                    cla(app.UIAxes2,'reset');
                    [time,vhtWaveform] = Waveform_ac(app);
                    plot(app.UIAxes2,time,abs(vhtWaveform));
                    xlabel (app.UIAxes2,'Time (microseconds)');
                    ylabel(app.UIAxes2,'Magnitude');
                    title(app.UIAxes2,'Baseband IEEE 802.11ac Waveform');
                    
                    %                     txWaveform = Waveform1(app);
                    %                     plot(app.UIAxes2,abs(txWaveform));
                    %                     xlabel(app.UIAxes2,'Sample index');
                    %                     ylabel(app.UIAxes2,'Magnitude');
                    %                     title(app.UIAxes2,'Baseband IEEE 802.11ac Waveform');
                    %                     legend(app.UIAxes2,'Transmit antenna 1');
                    %                     imshow('Baseband.png','parent',app.UIAxes2);
                    %                     figure;
                    %                     plot(abs(txWaveform));
                    %                     xlabel('Sample index');
                    %                     ylabel('Magnitude');
                    %                     title('Baseband IEEE 802.11ac Waveform');
                    %                     legend('Transmit antenna 1');
                case '802.11ax'
                    cla(app.UIAxes2,'reset');
                    [time,heWaveform] = Waveform_ax(app);
                    plot(app.UIAxes2,time,abs(heWaveform))
                    xlabel (app.UIAxes2,'Time (microseconds)');
                    ylabel(app.UIAxes2,'Magnitude');
                    title(app.UIAxes2,'Baseband IEEE 802.11ax Waveform');
            end
        end

        % Callback function
        function WIFITestUIFigureSizeChanged(app, event)
            % %              Give resolution for your previous screen:
            %              old_screen=[1920,1080];
            % %              Collect information about new screen:
            %              set(0,'units','pixels');
            %              Pix_SS = get(0,'screensize');
            % %              Assign coefficient of reduction/increase:
            %              k_hor=Pix_SS(3)/old_screen(1);
            %              k_vert=Pix_SS(4)/old_screen(2);
            % %              Get size of your original application UI:
            %              uisize = app.WIFITestUIFigure.Position;
            %              screenWidth = uisize(3);
            %              screenHeight = uisize(4);
            %              left = uisize(1);
            %              bottom = uisize(2);
            % %              Assign new size for the width and height:
            %              width = screenWidth*k_hor;
            %              height = screenHeight*k_vert;
            % %              Assign coefficient to reduce font sizes:
            %              k_font=(k_hor+k_vert)/2;
            %              drawnow;
            % %              Collect UI elements:
            %              comp=app.WIFITestUIFigure.Children;
            % %              Change UI element size to new scaled size:
            %              app.WIFITestUIFigure.Position = [left bottom width height];
            % %              Change locations, width, height, and font of each element:
            %              for i=1:numel(comp)
            %                  app.WIFITestUIFigure.Children(i).Position(3)=comp(i).Position(3)*k_hor;
            %                  app.WIFITestUIFigure.Children(i).Position(4)=comp(i).Position(4)*k_vert;
            %                  app.WIFITestUIFigure.Children(i).Position(1)=comp(i).Position(1)*k_hor;
            %                  app.WIFITestUIFigure.Children(i).Position(2)=comp(i).Position(2)*k_vert;
            % %                  Catch an error for possible UI elements not having FontSize
            %                      try
            %                      a{i} = comp(i).FontSize;
            %                      catch
            % %                      warning('This object does not have font option.  Assigning a value of 0.');
            %                      a{i} = 0;
            %                      end
            % %                      CHange FontSize of all elements that actually have
            % %                      FontSize component
            %                  if a{i}~=0
            %                  app.WIFITestUIFigure.Children(i).FontSize=comp(i).FontSize*k_font;
            %                  end
            %              end
        end

        % Callback function
        function WIFIMXGListBoxValueChanged(app, event)
            
            
        end

        % Callback function
        function DCSupplyEditFieldValueChanged2(app, event)
            
            
        end

        % Button pushed function: DC_Button1
        function DCButton1(app, event)
             V30 = app.V30EditField1.Value;
             V15 = app.V15EditField1.Value;
             DCplot(app,app.BoardIndex1,app.DCaddress1,V30,V15)
        end

        % Button pushed function: DC_Button2
        function DCButton2(app, event)
            V30 = app.V30EditField2.Value;
            V15 = app.V15EditField2.Value;
            DCplot(app,app.BoardIndex2,app.DCaddress2,V30,V15)
        end

        % Value changed function: V15EditField1, V30EditField1
        function DC1Voltvaluechanged(app, event)
            V30 = app.V30EditField1.Value;
            V15 = app.V15EditField1.Value;
            [CP30,CP15] = DCsupmes(app,app.BoardIndex1,app.DCaddress1,V30,V15);
            app.V30CurEditField1.Value=CP30;
            app.V15CurEditField1.Value=CP15;            
        end

        % Value changed function: V15EditField2, V30EditField2
        function DC2voltvaluechanged(app, event)
            V30 = app.V30EditField2.Value;
            V15 = app.V15EditField2.Value;
            [CP30,CP15] = DCsupmes(app,app.BoardIndex2,app.DCaddress2,V30,V15);
            app.V30CurEditField2.Value=CP30;
            app.V15CurEditField2.Value=CP15;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WIFITestUIFigure = uifigure;
            app.WIFITestUIFigure.Name = 'WIFI Test';
            app.WIFITestUIFigure.CloseRequestFcn = createCallbackFcn(app, @WIFITestUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.WIFITestUIFigure);
            app.FileMenu.Text = 'File ';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Separator = 'on';
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SettingMenu = uimenu(app.WIFITestUIFigure);
            app.SettingMenu.Text = 'Setting';
            app.VSGMenu = uimenu(app.SettingMenu);
            app.VSGMenu.Separator = 'on';
            app.VSGMenu.Accelerator = 'G';
            app.VSGMenu.Text = 'VSG';
            app.VSAMenu = uimenu(app.SettingMenu);
            app.VSAMenu.Separator = 'on';
            app.VSAMenu.Accelerator = 'A';
            app.VSAMenu.Text = 'VSA';
            app.HelpMenu = uimenu(app.WIFITestUIFigure);
            app.HelpMenu.Text = 'Help';
            app.LinkLabel = uilabel(app.WIFITestUIFigure);
            app.LinkLabel.Text = '汇芯 www.hatchip.com ';
            app.TilteLabel = uilabel(app.WIFITestUIFigure);
            app.TilteLabel.Text = 'WLAN 自动化测试系统';
            app.Panel3 = uipanel(app.WIFITestUIFigure);
            app.DCButtonGroup = uibuttongroup(app.Panel3);
            app.DCButtonGroup.Title = 'DC Supply(E3632A)';
            app.V30EditField1 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V30EditField1.Limits = [0 30];
            app.V30EditField1.ValueDisplayFormat = '%4.2f';
            app.V30EditField1.ValueChangedFcn = createCallbackFcn(app, @DC1Voltvaluechanged, true);
            app.V15EditField1 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V15EditField1.Limits = [0 15];
            app.V15EditField1.ValueDisplayFormat = '%4.2f';
            app.V15EditField1.ValueChangedFcn = createCallbackFcn(app, @DC1Voltvaluechanged, true);
            app.V30Label1 = uilabel(app.DCButtonGroup);
            app.V30Label1.Text = 'P30(V)';
            app.V30CurEditField1 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V30CurEditField1.Limits = [-0.1 4];
            app.V30CurEditField1.ValueDisplayFormat = '%6.3f';
            app.DC_Button2 = uibutton(app.DCButtonGroup, 'push');
            app.DC_Button2.ButtonPushedFcn = createCallbackFcn(app, @DCButton2, true);
            app.DC_Button2.Icon = 'DC.png';
            app.DC_Button2.Text = '电源2';
            app.DC_Button1 = uibutton(app.DCButtonGroup, 'push');
            app.DC_Button1.ButtonPushedFcn = createCallbackFcn(app, @DCButton1, true);
            app.DC_Button1.Icon = 'DC.png';
            app.DC_Button1.Text = '电源1';
            app.ILabel1 = uilabel(app.DCButtonGroup);
            app.ILabel1.Text = '电流(A)';
            app.V15EditField2 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V15EditField2.Limits = [0 15];
            app.V15EditField2.ValueDisplayFormat = '%4.2f';
            app.V15EditField2.ValueChangedFcn = createCallbackFcn(app, @DC2voltvaluechanged, true);
            app.V30EditField2 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V30EditField2.Limits = [0 30];
            app.V30EditField2.ValueDisplayFormat = '%4.2f';
            app.V30EditField2.ValueChangedFcn = createCallbackFcn(app, @DC2voltvaluechanged, true);
            app.V15Label1 = uilabel(app.DCButtonGroup);
            app.V15Label1.Text = 'P15(V)';
            app.V30Label2 = uilabel(app.DCButtonGroup);
            app.V30Label2.Text = 'P30(V)';
            app.V15Label2 = uilabel(app.DCButtonGroup);
            app.V15Label2.Text = 'P15(V)';
            app.V15CurEditField1 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V15CurEditField1.Limits = [-0.1 7];
            app.V15CurEditField1.ValueDisplayFormat = '%6.3f';
            app.ILabel2 = uilabel(app.DCButtonGroup);
            app.ILabel2.Text = '电流(A)';
            app.V30CurEditField2 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V30CurEditField2.Limits = [-0.1 4];
            app.V30CurEditField2.ValueDisplayFormat = '%6.3f';
            app.ILabel3 = uilabel(app.DCButtonGroup);
            app.ILabel3.Text = '电流(A)';
            app.V15CurEditField2 = uieditfield(app.DCButtonGroup, 'numeric');
            app.V15CurEditField2.Limits = [-0.1 7];
            app.V15CurEditField2.ValueDisplayFormat = '%6.3f';
            app.ILabel4 = uilabel(app.DCButtonGroup);
            app.ILabel4.Text = '电流(A)';
            app.MXAButton = uibutton(app.Panel3, 'push');
            app.MXAButton.ButtonPushedFcn = createCallbackFcn(app, @MXAButtonPushed, true);
            app.MXAButton.Icon = 'MXA1.png';
            app.MXAButton.Text = 'MXA';
            app.Panel = uipanel(app.WIFITestUIFigure);
            app.Panel2 = uipanel(app.Panel);
            app.PowerMeterEditFieldLabel = uilabel(app.Panel2);
            app.PowerMeterEditFieldLabel.Text = 'Power Meter';
            app.PowerMeterEditField = uieditfield(app.Panel2, 'numeric');
            app.PowerMeterEditField.Limits = [-20 -5];
            app.PowerMeterEditField.ValueDisplayFormat = '%5.2f';
            app.PowerMeterEditField.ValueChangedFcn = createCallbackFcn(app, @PowerMeterEditFieldValueChanged, true);
            app.PowerMeterEditField.Value = -5;
            app.dBLabel = uilabel(app.Panel2);
            app.dBLabel.Text = 'dB';
            app.Panel1 = uipanel(app.Panel);
            app.WIFIMXGListBoxLabel = uilabel(app.Panel1);
            app.WIFIMXGListBoxLabel.Text = 'WIFI MXG';
            app.WIFIMXGListBox = uilistbox(app.Panel1);
            app.WIFIMXGListBox.Items = {'选择波形', '802.11a/g', '802.11b', '802.11n', '802.11ac', '802.11ax'};
            app.WIFIMXGListBox.ValueChangedFcn = createCallbackFcn(app, @Selectwaveform, true);
            app.WIFIMXGListBox.Value = '选择波形';
            app.TabGroup = uitabgroup(app.WIFITestUIFigure);
            app.BasebandWaveformTab = uitab(app.TabGroup);
            app.BasebandWaveformTab.Title = 'Baseband Waveform';
            app.UIAxes2 = uiaxes(app.BasebandWaveformTab);
            app.VITab = uitab(app.TabGroup);
            app.VITab.Title = 'V/I';
            app.UIAxes6 = uiaxes(app.VITab);
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.UIAxes3 = uiaxes(app.PSDTab);
            app.ContellationDiagramTab = uitab(app.TabGroup);
            app.ContellationDiagramTab.Title = 'Contellation Diagram';
            app.UIAxes4 = uiaxes(app.ContellationDiagramTab);
            app.SpectrumAnalyzerTab = uitab(app.TabGroup);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.UIAxes5 = uiaxes(app.SpectrumAnalyzerTab);
            app.Button = uibutton(app.WIFITestUIFigure, 'push');
            app.Button.Icon = 'hatchip.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WLAN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WIFITestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WIFITestUIFigure)
        end
    end
end